classdef Insulators < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        InsulatorlengthPanel     matlab.ui.container.Panel
        calculatelengthButton    matlab.ui.control.Button
        pickButton               matlab.ui.control.Button
        Spinner                  matlab.ui.control.Spinner
        kVSpinnerLabel           matlab.ui.control.Label
        parameterPanel           matlab.ui.container.Panel
        Gridwidth                matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        clearButton              matlab.ui.control.Button
        Status                   matlab.ui.control.TextArea
        BatchesPanel             matlab.ui.container.Panel
        readButton_2             matlab.ui.control.Button
        Button_22                matlab.ui.control.Button
        DropDown_Ins             matlab.ui.control.DropDown
        batchesextractionButton  matlab.ui.control.Button
        DropDown                 matlab.ui.control.DropDown
        ModifytowertypePanel     matlab.ui.container.Panel
        ChangeType               matlab.ui.control.Button
        DropDown_2               matlab.ui.control.DropDown
        typeidentificationPanel  matlab.ui.container.Panel
        extractinsButton         matlab.ui.control.Button
        currentidButton          matlab.ui.control.Button
        AllTowersButton          matlab.ui.control.Button
        readButton               matlab.ui.control.Button
        Button_20                matlab.ui.control.Button
        DropDown_Type            matlab.ui.control.DropDown
        selectButton             matlab.ui.control.Button
        ProcessFolderEditField   matlab.ui.control.EditField
        Label_3                  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath % Description
        savepath
        TCName
        TowerIDs
        TowerTypes
        IsNzs
        TowerNZs
        InsLen
        InsPtsAll
        otherPa
        Remark
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');drawnow
            cd(fileparts(mfilename('fullpath')));% Set the file where the app is located as the working directory
        end

        % Button pushed function: selectButton
        function select_folder(app, event)
            app.filepath = uigetdir();
            if app.filepath ~= 0
                % Clear environment
                clear -except app.filepath
                % Save result path
                gapStr = strfind(app.filepath,'\');
                app.savepath = [app.filepath(1:gapStr(end)),'Res'];
                % file path
                app.ProcessFolderEditField.Value = app.filepath;
                % mkdir(app.savepath)
                % Transmission corridor name
                [~,app.TCName] = fileparts(fileparts(app.filepath));
                % Tower serial number
                app.TowerIDs = getTowerID(app.filepath);
                app.DropDown_Type.Items = cellstr(app.TowerIDs);
                app.DropDown_Ins.Items = cellstr(app.TowerIDs);
                app.DropDown.Value = app.DropDown.Items(1);
                %% Extract parameter initialization
                % Tower type
                app.TowerTypes = zeros(length(app.TowerIDs),2);
                app.TowerTypes(:,1) = str2double(string(app.TowerIDs));
                % Insulator length
                app.InsLen = app.TowerTypes;
                % Is tension tower or ont
                app.IsNzs = app.TowerTypes;
                % Insulator points
                app.InsPtsAll = cell(size(app.TowerTypes,1),1);
                % Other parameters
                app.otherPa = zeros(length(app.TowerIDs),17);
                app.otherPa(:,1) = str2double(string(app.TowerIDs));
                % Remark
                app.Remark = strings(length(app.TowerIDs),1);
            end
        end

        % Button pushed function: Button_20
        function last_one1(app, event)
            % Switch to the previous tower
            NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))-1;
            if NextID <= length(app.DropDown_Type.Items) && NextID > 0
                app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
            end
        end

        % Button pushed function: readButton
        function read_pts1(app, event)
            TowerID = app.DropDown_Type.Value;
            Tfilename = [app.filepath,'\',TowerID,'Tower.txt'];
            TowerPts = importdata(Tfilename);% drowPts(TowerPts,'.r')
            %% Redirect the power tower point cloud (noise points may exist above the power tower point cloud)
            [TopZ,TopInd] = maxk(TowerPts(:,3),10); % There will be no more than 10 noise points
            cutInd = TopInd(find(diff(TopZ) < -1,1,'last')+1); % drowPts(TowerPts(TopInd,:),'.b',TowerPts(cutInd,:),'.r')
            if ~isempty(cutInd)
                TowerPts = TowerPts(TowerPts(:,3) <= TowerPts(cutInd,3),:);% drowPts(cutPts,'.r')
            end
            % Intercept the 3m point cloud on the power tower to calculate the main direction
            [~,Theta] = RotawithAxle(TowerPts(TowerPts(:,3)>max(TowerPts(:,3))-3,:),3);
            TowerPtsR = TowerPts*rotz(Theta*180/pi);% drowPts(TowerPtsR,'.r')
            %% Read powerline points and redirect
            LinePts = importdata(strrep(Tfilename,'Tower','Line')); % drowPts(LinePts,'.r')
            LinePtsR = LinePts * rotz(Theta*180/pi); % drowPts(TowerPtsR,'.b',LinePtsR,'.r')
            InsPts = app.InsPtsAll{find(strcmp(app.DropDown_Type.Items, TowerID))};
            %% Show point cloud
            TowerType = ["wine","cat"," T","Gan","Tension drum","Suspension drum","Rod-shaped","door"];
            if ~isempty(InsPts)
                drowPtsUI(TowerPtsR,'.b',LinePtsR,'.g',InsPts,'.r',app.UIAxes)
            else
                drowPtsUI(TowerPtsR,'.b',LinePtsR,'.g',app.UIAxes)
            end
            Ind = strfind(Tfilename,'\');
            selectTowerID = str2double(app.DropDown_Type.Value);
            TypeID = app.TowerTypes(app.TowerTypes(:,1)==selectTowerID, 2);
            IsNz = app.IsNzs(app.IsNzs(:,1)==selectTowerID, 2);
            if TypeID ~= 0
                app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'Tower：',char(TowerType(TypeID))]);
            else
                app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'Tower：','Unknown tower type']);
            end
            % Switch to next tower
            NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))+1;
            if NextID <= length(app.DropDown_Type.Items) && NextID > 0
                app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
            end
        end

        % Button pushed function: currentidButton
        function single_type(app, event)
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'Tower')-1);
            Tfilename = [app.filepath,'\',TowerID,'Tower.txt'];
            TowerPts = importdata(Tfilename);
            LinePts = importdata([app.filepath,'\',TowerID,'Line.txt']);
            TypeID = TypeDetect(TowerPts,LinePts);
            app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2) = TypeID;
            % display type
            Ind = strfind(Tfilename,'\');
            TowerType = ["wine","cat"," T","Gan","Tension drum","Suspension drum","Rod-shaped","door"];
            app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'Tower：',char(TowerType(TypeID))]);
            % read_pts1(app,event);
        end

        % Button pushed function: AllTowersButton
        function type_detect(app, event)
              %The file does not exist or choose to re-detect it.
              app.Status.Value = [app.Status.Value;'Start detecting the tower types of all towers in the folder...'];drawnow
              TowerNum = length(app.TowerIDs);% Number of power towers
              for i = 1:TowerNum
                  TowerIDstr = app.TowerIDs(i,:);
                  Tfilename = [app.filepath,'\',TowerIDstr,'Tower.txt'];
                  % Identify tower type
                  TowerPts = importdata(Tfilename);
                  LinePts = importdata([app.filepath,'\',TowerIDstr,'Line.txt']);
                  TypeID = TypeDetect(TowerPts,LinePts);
                  % [TypeId,Centre] = TypeDetect(Tfilename);
                  % Save current tower
                  app.TowerTypes(i,2) = TypeID;
                  app.Status.Value = [app.Status.Value;['Tower',TowerIDstr,' Test completed (Processing progress:',num2str(i),'/',num2str(TowerNum),')']];drawnow
              end
              % Save all results
              % savefilename = [app.savepath,'\TowerType.txt'];
              % writematrix(app.TowerTypes,savefilename)
              app.Status.Value = [app.Status.Value;['transmission lines:',app.TCName,' Type identification completed']];
            
        end

        % Button pushed function: extractinsButton
        function single_Insulator(app, event)
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'Tower')-1);
            Tfilename = [app.filepath,'\',TowerID,'Tower.txt'];
            TowerPts = importdata(Tfilename);% drowPts(TowerPts,'.r')
            %% Redirect the power tower point cloud (noise points may exist above the power tower point cloud)
            [TopZ,TopInd] = maxk(TowerPts(:,3),10); % There will be no more than 10 noise points
            cutInd = TopInd(find(diff(TopZ) < -1,1,'last')+1); % drowPts(TowerPts(TopInd,:),'.b',TowerPts(cutInd,:),'.r')
            if ~isempty(cutInd)
                TowerPts = TowerPts(TowerPts(:,3) <= TowerPts(cutInd,3),:);% drowPts(cutPts,'.r')
            end
            % Intercept the 3m point cloud on the power tower to calculate the main direction
            [~,Theta] = RotawithAxle(TowerPts(TowerPts(:,3)>max(TowerPts(:,3))-3,:),3);
            TowerPtsR = TowerPts*rotz(Theta*180/pi);% drowPts(TowerPtsR,'.r')
            %% Read powerline points and redirect
            LinePts = importdata(strrep(Tfilename,'Tower','Line')); % drowPts(LinePts,'.r')
            LinePtsR = LinePts * rotz(Theta*180/pi); % drowPts(TowerPtsR,'.b',LinePtsR,'.r')
            %% Get tower type
            TypeID = app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2);
            if TypeID == 0
                app.Status.Value = [app.Status.Value;"Tower type unknown"];drawnow
                return
            end
            %% Extract insulators
            InsPts = TypeInsdeTree(TowerPtsR,LinePtsR,app.Gridwidth.Value,TypeID);
            MeasPts = mergeCell3(InsPts);
            app.InsPtsAll{find(strcmp(app.DropDown_Type.Items, TowerID))} = MeasPts;
            %% Show points
            drowPtsUI(TowerPtsR,'.b',LinePtsR,'.g',MeasPts,'.r',app.UIAxes)
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'Tower')-1);
            % app.InsLenLabel.Text = num2str(app.InsLen(app.InsLen(:,1)==str2double(TowerID),2));
            app.Status.Value = [app.Status.Value;string([TowerID,'Tower, Insulator extraction completed'])];
        end

        % Button pushed function: batchesextractionButton
        function Insulator_extract(app, event)
            TowerIDIt = app.DropDown_Ins.Items;
            for i = 1:size(TowerIDIt,2)
                TowerID = TowerIDIt{i};
                Tfilename = [app.filepath,'\',TowerID,'Tower.txt'];
                TowerPts = importdata(Tfilename);% drowPts(TowerPts,'.r')
                %% Redirect the power tower point cloud (noise points may exist above the power tower point cloud)
                [TopZ,TopInd] = maxk(TowerPts(:,3),10); % There will be no more than 10 noise points
                cutInd = TopInd(find(diff(TopZ) < -1,1,'last')+1); % drowPts(TowerPts(TopInd,:),'.b',TowerPts(cutInd,:),'.r')
                if ~isempty(cutInd)
                    TowerPts = TowerPts(TowerPts(:,3) <= TowerPts(cutInd,3),:);% drowPts(cutPts,'.r')
                end
                % Intercept the 3m point cloud on the power tower to calculate the main direction
                [~,Theta] = RotawithAxle(TowerPts(TowerPts(:,3)>max(TowerPts(:,3))-3,:),3);
                TowerPtsR = TowerPts*rotz(Theta*180/pi);% drowPts(TowerPtsR,'.r')
                %% Read powerline points and redirect
                LinePts = importdata(strrep(Tfilename,'Tower','Line')); % drowPts(LinePts,'.r')
                LinePtsR = LinePts * rotz(Theta*180/pi); % drowPts(TowerPtsR,'.b',LinePtsR,'.r')
                %% Get tower type
                TypeID = app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2);
                isNz = app.IsNzs(app.IsNzs(:,1)==str2double(TowerID),2);
                if TypeID == 0
                    [TypeID,isNz] = TypeDetect220kV(Tfilename);
                    app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2) = TypeID;
                    app.IsNzs(app.IsNzs(:,1)==str2double(TowerID),2) = isNz;
                end
                %% Extract insulators
                InsPts = TypeInsdeTree(TowerPtsR,LinePtsR,app.Gridwidth.Value,TypeID);
                MeasPts = mergeCell3(InsPts);
                app.InsPtsAll{find(strcmp(app.DropDown_Type.Items, TowerID))} = MeasPts;
                app.Status.Value = [app.Status.Value;string([TowerID,'Tower, Insulator extraction completed'])];drawnow
            end
            app.Status.Value = [app.Status.Value;[app.DropDown.Value,' insulator extraction completed']];
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if ~strcmp(app.DropDown.Value,'All towers')
                TowerType = ["wine","cat"," T","Gan","Tension drum","Suspension drum","Rod-shaped","door"];
                TowerIDsInd = find(app.TowerTypes(:,2) == find(TowerType == string(app.DropDown.Value)));
            else% All towers
                TowerIDsInd = 1:length(app.TowerIDs);
            end
            app.DropDown_Ins.Items = cellstr(app.TowerIDs(TowerIDsInd,:));  
        end

        % Button pushed function: Button_22
        function last_one2(app, event)
            % Switch to next tower
            NextID = find(strcmp(app.DropDown_Ins.Items,app.DropDown_Ins.Value))-1;
            if NextID <= length(app.DropDown_Ins.Items) && NextID > 0
                app.DropDown_Ins.Value = app.DropDown_Ins.Items{NextID};
            end
        end

        % Button pushed function: ChangeType
        function change_type(app, event)
            select_Type = find(strcmp(app.DropDown_2.Items,app.DropDown_2.Value))-1;
            select_Tower = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'Tower')-1);
            app.TowerTypes(app.TowerTypes(:,1) == str2double(select_Tower),2) = select_Type;
            app.Status.Value = [app.Status.Value;['Tower',select_Tower,'Type modified']];
            read_pts1(app,event)
        end

        % Button pushed function: clearButton
        function ClearStatus(app, event)
            app.Status.Value = ' ';
        end

        % Button pushed function: readButton_2
        function read_pts2(app, event)
            app.DropDown_Type.Value = app.DropDown_Ins.Value;
            read_pts1(app, event)
            % Switch to next tower
            NextID = find(strcmp(app.DropDown_Ins.Items,app.DropDown_Ins.Value))+1;
            if NextID <= length(app.DropDown_Ins.Items) && NextID > 0
                app.DropDown_Ins.Value = app.DropDown_Ins.Items{NextID};
            end
        end

        % Button pushed function: pickButton
        function pickButtonPushed(app, event)
            % Datas = app.UIAxes.Children;
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'Tower')-1);
            %% Read insulator points
            InsPts = app.InsPtsAll{strcmp(app.DropDown_Ins.Items,TowerID)};
            InsPts = InsPts(:,1:3);
            data_tip = app.UIAxes.Children(1).Children;
            if isempty(data_tip)
                msgbox("no select points")
                return
            end
            selectPtR = [data_tip.X,data_tip.Y,data_tip.Z];
%             drowPts(InsPtsRCut,'.b',selectPtR,'.r')
            labels = dbscan(InsPts,app.Spinner.Value,3);
            Unilabels = unique(labels);
%             drowPts([InsPts,labels],length(Unilabels))
            minDist = inf;
            for i = 1:length(Unilabels)
                CurminDist = min(vecnorm(InsPts(labels==Unilabels(i),:) - selectPtR, 2, 2));
                if CurminDist < minDist
                    minDist = CurminDist;
                    selectLabel = Unilabels(i);
                end
            end
            drowPtsUI(InsPts(labels==selectLabel,:),'.r',InsPts(labels~=selectLabel,:),'.b',app.UIAxes)
        end

        % Button pushed function: calculatelengthButton
        function calculatelengthButtonPushed(app, event)
            Line = app.UIAxes.Children(2); 
            InsPts = [Line.XData',Line.YData',Line.ZData',];% drowPts(InsPts,'.r')
            InsPtsR3 = RotawithAxle(InsPts,3);% drowPts(InsPtsR3,'.r')
            InsPtsR32 = RotawithAxle(InsPtsR3,2);% drowPts(InsPtsR32,'.r')
            Len = max(InsPtsR32(:,1)) - min(InsPtsR32(:,1));
            % Save insulator length
            TowerID = str2double(app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'Tower')-1));
            app.InsLen(app.InsLen(:,1)==TowerID,2) = Len;
            % Resave results
            % savefilename = ['.\Res\',app.TCName,'_InsLen.txt'];
            % writematrix(app.InsLen,savefilename)
            % display length
            app.Status.Value = [app.Status.Value;[app.TCName,' Tower',num2str(TowerID),' Insulator Length == ',num2str(Len)]];
            read_pts1(app, event)
            % Switch to the previous tower
            NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))-1;
            if NextID <= length(app.DropDown_Type.Items) && NextID > 0
                app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
            end
            % read_pts2(app, event)
            % app.InsLenLabel.Text = ['InsLen = ',num2str(Len)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Process Folder';
            app.ProcessFolderEditField = uieditfield(app.GridLayout, 'text');
            app.ProcessFolderEditField.Editable = 'off';
            app.selectButton = uibutton(app.GridLayout, 'push');
            app.selectButton.ButtonPushedFcn = createCallbackFcn(app, @select_folder, true);
            app.selectButton.Text = 'select';
            app.typeidentificationPanel = uipanel(app.GridLayout);
            app.typeidentificationPanel.Title = 'type identification';
            app.DropDown_Type = uidropdown(app.typeidentificationPanel);
            app.DropDown_Type.Items = {'IDs'};
            app.DropDown_Type.Value = 'IDs';
            app.Button_20 = uibutton(app.typeidentificationPanel, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @last_one1, true);
            app.Button_20.Text = '-';
            app.readButton = uibutton(app.typeidentificationPanel, 'push');
            app.readButton.ButtonPushedFcn = createCallbackFcn(app, @read_pts1, true);
            app.readButton.Text = 'read';
            app.AllTowersButton = uibutton(app.typeidentificationPanel, 'push');
            app.AllTowersButton.ButtonPushedFcn = createCallbackFcn(app, @type_detect, true);
            app.AllTowersButton.Text = 'All Towers';
            app.currentidButton = uibutton(app.typeidentificationPanel, 'push');
            app.currentidButton.ButtonPushedFcn = createCallbackFcn(app, @single_type, true);
            app.currentidButton.Text = 'current id';
            app.extractinsButton = uibutton(app.typeidentificationPanel, 'push');
            app.extractinsButton.ButtonPushedFcn = createCallbackFcn(app, @single_Insulator, true);
            app.extractinsButton.Text = 'extract ins';
            app.ModifytowertypePanel = uipanel(app.GridLayout);
            app.ModifytowertypePanel.Title = 'Modify tower type';
            app.DropDown_2 = uidropdown(app.ModifytowertypePanel);
            app.DropDown_2.Items = {'select type', 'wine', 'cat', 'T', 'Gan', 'Tension drum', 'Suspension drum', 'Rod-shaped', 'door'};
            app.DropDown_2.Value = 'select type';
            app.ChangeType = uibutton(app.ModifytowertypePanel, 'push');
            app.ChangeType.ButtonPushedFcn = createCallbackFcn(app, @change_type, true);
            app.ChangeType.Text = 'modify type';
            app.BatchesPanel = uipanel(app.GridLayout);
            app.BatchesPanel.Title = 'Batches';
            app.DropDown = uidropdown(app.BatchesPanel);
            app.DropDown.Items = {'select type', 'wine', 'cat', 'T', 'Gan', 'Tension drum', 'Suspension drum', 'Rod-shaped', 'door'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'select type';
            app.batchesextractionButton = uibutton(app.BatchesPanel, 'push');
            app.batchesextractionButton.ButtonPushedFcn = createCallbackFcn(app, @Insulator_extract, true);
            app.batchesextractionButton.Text = 'batches extraction ';
            app.DropDown_Ins = uidropdown(app.BatchesPanel);
            app.DropDown_Ins.Items = {'IDs'};
            app.DropDown_Ins.Value = 'IDs';
            app.Button_22 = uibutton(app.BatchesPanel, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @last_one2, true);
            app.Button_22.Text = '-';
            app.readButton_2 = uibutton(app.BatchesPanel, 'push');
            app.readButton_2.ButtonPushedFcn = createCallbackFcn(app, @read_pts2, true);
            app.readButton_2.Text = 'read';
            app.Status = uitextarea(app.GridLayout);
            app.Status.Editable = 'off';
            app.clearButton = uibutton(app.GridLayout, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearStatus, true);
            app.clearButton.Text = 'clear';
            app.parameterPanel = uipanel(app.GridLayout);
            app.parameterPanel.Title = 'parameter';
            app.Label_4 = uilabel(app.parameterPanel);
            app.Label_4.Text = 'Grid width';
            app.Gridwidth = uieditfield(app.parameterPanel, 'numeric');
            app.Gridwidth.Value = 0.1;
            app.InsulatorlengthPanel = uipanel(app.GridLayout);
            app.InsulatorlengthPanel.Title = 'Insulator length';
            app.kVSpinnerLabel = uilabel(app.InsulatorlengthPanel);
            app.kVSpinnerLabel.Text = '220kV';
            app.Spinner = uispinner(app.InsulatorlengthPanel);
            app.Spinner.Value = 0.5;
            app.pickButton = uibutton(app.InsulatorlengthPanel, 'push');
            app.pickButton.ButtonPushedFcn = createCallbackFcn(app, @pickButtonPushed, true);
            app.pickButton.Text = 'pick';
            app.calculatelengthButton = uibutton(app.InsulatorlengthPanel, 'push');
            app.calculatelengthButton.ButtonPushedFcn = createCallbackFcn(app, @calculatelengthButtonPushed, true);
            app.calculatelengthButton.Text = 'calculate length';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Insulators

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end