classdef Insulators < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        Panel_8         matlab.ui.container.Panel
        Button_27       matlab.ui.control.Button
        Button_26       matlab.ui.control.Button
        Spinner         matlab.ui.control.Spinner
        kVSpinnerLabel  matlab.ui.control.Label
        Panel_7         matlab.ui.container.Panel
        Gridwidth       matlab.ui.control.NumericEditField
        Label_4         matlab.ui.control.Label
        Button_29       matlab.ui.control.Button
        Status          matlab.ui.control.TextArea
        Panel_5         matlab.ui.container.Panel
        Button_23       matlab.ui.control.Button
        Button_22       matlab.ui.control.Button
        DropDown_Ins    matlab.ui.control.DropDown
        Button_15       matlab.ui.control.Button
        DropDown        matlab.ui.control.DropDown
        Panel_6         matlab.ui.container.Panel
        ChangeType      matlab.ui.control.Button
        DropDown_2      matlab.ui.control.DropDown
        Panel_4         matlab.ui.container.Panel
        Button_5        matlab.ui.control.Button
        Button_17       matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        Button_21       matlab.ui.control.Button
        Button_20       matlab.ui.control.Button
        DropDown_Type   matlab.ui.control.DropDown
        Button_6        matlab.ui.control.Button
        EditField_2     matlab.ui.control.EditField
        Label_3         matlab.ui.control.Label
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath % Description
        savepath
        TCName
        TowerIDs
        TowerTypes
        IsNzs
        TowerNZs
        InsLen
        InsPtsAll
        otherPa
        Remark
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');drawnow
            cd(fileparts(mfilename('fullpath')));% 将app所在的文件设置为工作目录
            % addpath(genpath('.\Functional'))% 添加功能函数
        end

        % Button pushed function: Button_6
        function select_folder(app, event)
            app.filepath = uigetdir();
            if app.filepath ~= 0
                if ~contains(app.filepath,'电塔序号')
                    app.filepath = [app.filepath,'\电塔序号'];
                end
                % 清空环境
                clear -except app.filepath
                % 保存结果路径
                gapStr = strfind(app.filepath,'\');
                app.savepath = [app.filepath(1:gapStr(end)),'Res'];
%                 if exist(app.savepath,"dir")
%                     answer = questdlg('重新处理数据将删除现有结果，确认当前结果已保存','提示',"Yep","No","Yep");
%                     if answer == "No"
%                         return
%                     else
%                         rmdir(app.savepath, 's');
%                     end
%                 end
                % 文件路径
                app.EditField_2.Value = app.filepath;
                % mkdir(app.savepath)
                % 输电走廊名称
                [~,app.TCName] = fileparts(fileparts(app.filepath)); % 220kV嵩雨线
                % 电塔序号
                app.TowerIDs = getTowerID(app.filepath);
                app.DropDown_Type.Items = cellstr(app.TowerIDs);
                app.DropDown_Ins.Items = cellstr(app.TowerIDs);
                app.DropDown.Value = app.DropDown.Items(1);
                %% 提取参数初始化
                % 电塔类型
                app.TowerTypes = zeros(length(app.TowerIDs),2);
                app.TowerTypes(:,1) = str2double(string(app.TowerIDs));
                % 绝缘子长度
                app.InsLen = app.TowerTypes;
                % 是否为耐张塔
                app.IsNzs = app.TowerTypes;
                % 绝缘子点云
                app.InsPtsAll = cell(size(app.TowerTypes,1),1);
                % 其他参数
                app.otherPa = zeros(length(app.TowerIDs),17);
                app.otherPa(:,1) = str2double(string(app.TowerIDs));
                % 备注
                app.Remark = strings(length(app.TowerIDs),1);
%                 % 加载已计算过的绝缘子长度
%                 TowerNZName = ['.\Data\Res\insLen\',app.TCName,'_InsLen.txt'];
%                 if exist(TowerNZName,'file')
%                     app.InsLen = importdata(TowerNZName);
%                 else
%                     app.InsLen = zeros(size(app.TowerTypes,1),2);
%                     app.InsLen(:,1) = app.TowerTypes (:,1);
%                 end
            end
        end

        % Button pushed function: Button_20
        function last_one1(app, event)
            % 切换到上一个电塔
            NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))-1;
            if NextID <= length(app.DropDown_Type.Items) && NextID > 0
                app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
            end
        end

        % Button pushed function: Button_21
        function read_pts1(app, event)
            TowerID = app.DropDown_Type.Value;
            Tfilename = [app.filepath,'\',TowerID,'杆塔.txt'];
            TowerPts = importdata(Tfilename);% drowPts(TowerPts,'.r')
            %% 重定向电塔点云(电塔点云上方可能存在噪声点)
            [TopZ,TopInd] = maxk(TowerPts(:,3),10); % 噪声点不会超过10个
            cutInd = TopInd(find(diff(TopZ) < -1,1,'last')+1); % drowPts(TowerPts(TopInd,:),'.b',TowerPts(cutInd,:),'.r')
            if ~isempty(cutInd)
                TowerPts = TowerPts(TowerPts(:,3) <= TowerPts(cutInd,3),:);% drowPts(cutPts,'.r')
            end
            % 截取电塔上3m点云进行计算主方向
            [~,Theta] = RotawithAxle(TowerPts(TowerPts(:,3)>max(TowerPts(:,3))-3,:),3);
            TowerPtsR = TowerPts*rotz(Theta*180/pi);% drowPts(TowerPtsR,'.r')
            %% 读取电力线点并重定向
            LinePts = importdata(strrep(Tfilename,'杆塔','电力线')); % drowPts(LinePts,'.r')
            LinePtsR = LinePts * rotz(Theta*180/pi); % drowPts(TowerPtsR,'.b',LinePtsR,'.r')
            InsPts = app.InsPtsAll{find(strcmp(app.DropDown_Type.Items, TowerID))};
            %% 显示点云
            TowerType = ["酒杯塔","猫头塔"," T 型塔","干型塔","鼓型塔","鼓型塔","杆状塔","门型塔"];
            if ~isempty(InsPts)
                drowPtsUI(TowerPtsR,'.b',LinePtsR,'.g',InsPts,'.r',app.UIAxes)
            else
                drowPtsUI(TowerPtsR,'.b',LinePtsR,'.g',app.UIAxes)
            end
            Ind = strfind(Tfilename,'\');
            selectTowerID = str2double(app.DropDown_Type.Value);
            TypeID = app.TowerTypes(app.TowerTypes(:,1)==selectTowerID, 2);
            IsNz = app.IsNzs(app.IsNzs(:,1)==selectTowerID, 2);
            if TypeID ~= 0
                app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'杆塔：',char(TowerType(TypeID))]);
            else
                app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'杆塔：','未知电塔类型']);
            end
            % 切换到下一个电塔
            NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))+1;
            if NextID <= length(app.DropDown_Type.Items) && NextID > 0
                app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
            end
        end

        % Button pushed function: Button_17
        function single_type(app, event)
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'杆塔')-1);
            Tfilename = [app.filepath,'\',TowerID,'杆塔.txt'];
            TowerPts = importdata(Tfilename);
            LinePts = importdata([app.filepath,'\',TowerID,'电力线.txt']);
            TypeID = TypeDetect(TowerPts,LinePts);
            app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2) = TypeID;
            % 显示类型
            Ind = strfind(Tfilename,'\');
            TowerType = ["酒杯塔","猫头塔"," T 型塔","干型塔","耐张鼓型塔","直流鼓型塔","杆状电塔","门型塔"];
            app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'杆塔：',char(TowerType(TypeID))]);
            % read_pts1(app,event);
        end

        % Button pushed function: Button_3
        function type_detect(app, event)
%             TowerTypeName = ['.\TepVar\',app.TCName,'_TowerType.txt'];
            readFile = 1; %是否需要检测电塔类型
%             if exist(TowerTypeName,'file')
%                 answer = questdlg('电塔类型结果文件已存在，是否重新生成？  ','提示',"Yep","No","No");
%                 if answer == "No"
%                     readFile = 0;
%                     app.TowerTypes = importdata(TowerTypeName);
%                     app.Lamp.Color = [0.00,1.00,0.00];
%                 end
%             end
            if readFile %文件不存在或者选择重新检测
                app.Status.Value = [app.Status.Value;'开始检测文件夹下所有电塔的电塔类型...'];drawnow
                TowerNum = length(app.TowerIDs);% 电塔数量
                % app.TowerTypes = zeros(TowerNum,2);% 保存[电塔编号,中心坐标,电塔类型]
                for i = 1:TowerNum
                    TowerIDstr = app.TowerIDs(i,:);
                    Tfilename = [app.filepath,'\',TowerIDstr,'杆塔.txt'];
                    % 识别电塔类型
                    TowerPts = importdata(Tfilename);
                    LinePts = importdata([app.filepath,'\',TowerIDstr,'电力线.txt']);
                    TypeID = TypeDetect(TowerPts,LinePts);
                    % [TypeId,Centre] = TypeDetect(Tfilename);
                    % 保存当前电塔
                    app.TowerTypes(i,2) = TypeID;
                    app.Status.Value = [app.Status.Value;['杆塔',TowerIDstr,' 检测完毕 (处理进度:',num2str(i),'/',num2str(TowerNum),')']];drawnow
                end
                % 保存所有结果
                % savefilename = [app.savepath,'\TowerType.txt'];
                % writematrix(app.TowerTypes,savefilename)
                app.Status.Value = [app.Status.Value;['输电线路:',app.TCName,' 类型识别完毕']];
            end
        end

        % Button pushed function: Button_5
        function single_Insulator(app, event)
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'杆塔')-1);
            Tfilename = [app.filepath,'\',TowerID,'杆塔.txt'];
            TowerPts = importdata(Tfilename);% drowPts(TowerPts,'.r')
            %% 重定向电塔点云(电塔点云上方可能存在噪声点)
            [TopZ,TopInd] = maxk(TowerPts(:,3),10); % 噪声点不会超过10个
            cutInd = TopInd(find(diff(TopZ) < -1,1,'last')+1); % drowPts(TowerPts(TopInd,:),'.b',TowerPts(cutInd,:),'.r')
            if ~isempty(cutInd)
                TowerPts = TowerPts(TowerPts(:,3) <= TowerPts(cutInd,3),:);% drowPts(cutPts,'.r')
            end
            % 截取电塔上3m点云进行计算主方向
            [~,Theta] = RotawithAxle(TowerPts(TowerPts(:,3)>max(TowerPts(:,3))-3,:),3);
            TowerPtsR = TowerPts*rotz(Theta*180/pi);% drowPts(TowerPtsR,'.r')
            %% 读取电力线点并重定向
            LinePts = importdata(strrep(Tfilename,'杆塔','电力线')); % drowPts(LinePts,'.r')
            LinePtsR = LinePts * rotz(Theta*180/pi); % drowPts(TowerPtsR,'.b',LinePtsR,'.r')
            %% 获取杆塔类型
            TowerType = ["酒杯塔","猫头塔"," T 型塔","干型塔","耐张鼓型塔","直流鼓型塔","杆状电塔","门型塔"];
            Ind = strfind(Tfilename,'\');
            TypeID = app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2);
            if TypeID == 0
                app.Status.Value = [app.Status.Value;"杆塔类型未知"];drawnow
                return
            end
            %% 提取绝缘子
            InsPts = TypeInsdeTree(TowerPtsR,LinePtsR,app.Gridwidth.Value,TypeID);
            MeasPts = mergeCell3(InsPts);
            app.InsPtsAll{find(strcmp(app.DropDown_Type.Items, TowerID))} = MeasPts;
            % writematrix(MeasPts,[app.savepath,'/杆塔',TowerID,'_ins.txt'])
            %% 显示点
            drowPtsUI(TowerPtsR,'.b',LinePtsR,'.g',MeasPts,'.r',app.UIAxes)
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'杆塔')-1);
            % app.InsLenLabel.Text = num2str(app.InsLen(app.InsLen(:,1)==str2double(TowerID),2));
            app.Status.Value = [app.Status.Value;string([TowerID,'杆塔，绝缘子提取完毕'])];
            %% 切换到下一个电塔
%             NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))+1;
%             if NextID <= length(app.DropDown_Type.Items) && NextID > 0
%                 app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
%             end
        end

        % Button pushed function: Button_15
        function Insulator_extract(app, event)
            TowerIDIt = app.DropDown_Ins.Items;
            for i = 1:size(TowerIDIt,2)
                TowerID = TowerIDIt{i};
                Tfilename = [app.filepath,'\',TowerID,'杆塔.txt'];
                TowerPts = importdata(Tfilename);% drowPts(TowerPts,'.r')
                %% 重定向电塔点云(电塔点云上方可能存在噪声点)
                [TopZ,TopInd] = maxk(TowerPts(:,3),10); % 噪声点不会超过10个
                cutInd = TopInd(find(diff(TopZ) < -1,1,'last')+1); % drowPts(TowerPts(TopInd,:),'.b',TowerPts(cutInd,:),'.r')
                if ~isempty(cutInd)
                    TowerPts = TowerPts(TowerPts(:,3) <= TowerPts(cutInd,3),:);% drowPts(cutPts,'.r')
                end
                % 截取电塔上3m点云进行计算主方向
                [~,Theta] = RotawithAxle(TowerPts(TowerPts(:,3)>max(TowerPts(:,3))-3,:),3);
                TowerPtsR = TowerPts*rotz(Theta*180/pi);% drowPts(TowerPtsR,'.r')
                %% 读取电力线点并重定向
                LinePts = importdata(strrep(Tfilename,'杆塔','电力线')); % drowPts(LinePts,'.r')
                LinePtsR = LinePts * rotz(Theta*180/pi); % drowPts(TowerPtsR,'.b',LinePtsR,'.r')
                %% 获取杆塔类型
                % TowerType = ["酒杯塔","猫头塔"," T 型塔","干型塔","耐张鼓型塔","直流鼓型塔","杆状电塔","门型塔"];
                % Ind = strfind(Tfilename,'\');
                TypeID = app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2);
                isNz = app.IsNzs(app.IsNzs(:,1)==str2double(TowerID),2);
                if TypeID == 0
                    [TypeID,isNz] = TypeDetect220kV(Tfilename);
                    app.TowerTypes(app.TowerTypes(:,1)==str2double(TowerID),2) = TypeID;
                    app.IsNzs(app.IsNzs(:,1)==str2double(TowerID),2) = isNz;
                end
                % app.UIAxes.Title.String = string([Tfilename(Ind(end)+1:Ind(end)+3),'杆塔：',char(TowerType(TypeID))]);
                %% 提取绝缘子
                InsPts = TypeInsdeTree(TowerPtsR,LinePtsR,app.Gridwidth.Value,TypeID);
                MeasPts = mergeCell3(InsPts);
                app.InsPtsAll{find(strcmp(app.DropDown_Type.Items, TowerID))} = MeasPts;
                % writematrix(MeasPts,[app.savepath,'/杆塔',TowerID,'_ins.txt'])
                app.Status.Value = [app.Status.Value;string([TowerID,'杆塔，绝缘子提取完毕'])];drawnow
            end
            app.Status.Value = [app.Status.Value;[app.DropDown.Value,'类型绝缘子提取完毕']];
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            if ~strcmp(app.DropDown.Value,'所有电塔')
                TowerType = ["酒杯塔","猫头塔"," T 型塔","干型塔","耐张鼓型塔","直流鼓型塔","杆状塔","门型塔"];
                TowerIDsInd = find(app.TowerTypes(:,2) == find(TowerType == string(app.DropDown.Value)));
            else% 所有电塔
                TowerIDsInd = 1:length(app.TowerIDs);
            end
            app.DropDown_Ins.Items = cellstr(app.TowerIDs(TowerIDsInd,:));  
        end

        % Button pushed function: Button_22
        function last_one2(app, event)
            % 切换到下一个电塔
            NextID = find(strcmp(app.DropDown_Ins.Items,app.DropDown_Ins.Value))-1;
            if NextID <= length(app.DropDown_Ins.Items) && NextID > 0
                app.DropDown_Ins.Value = app.DropDown_Ins.Items{NextID};
            end
        end

        % Button pushed function: ChangeType
        function change_type(app, event)
            select_Type = find(strcmp(app.DropDown_2.Items,app.DropDown_2.Value))-1;
            select_Tower = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'杆塔')-1);
            app.TowerTypes(app.TowerTypes(:,1) == str2double(select_Tower),2) = select_Type;
            app.Status.Value = [app.Status.Value;['电塔',select_Tower,'类型已修改']];
            read_pts1(app,event)
        end

        % Button pushed function: Button_29
        function ClearStatus(app, event)
            app.Status.Value = ' ';
        end

        % Button pushed function: Button_23
        function read_pts2(app, event)
            app.DropDown_Type.Value = app.DropDown_Ins.Value;
            read_pts1(app, event)
            % 切换到下一个电塔
            NextID = find(strcmp(app.DropDown_Ins.Items,app.DropDown_Ins.Value))+1;
            if NextID <= length(app.DropDown_Ins.Items) && NextID > 0
                app.DropDown_Ins.Value = app.DropDown_Ins.Items{NextID};
            end
        end

        % Button pushed function: Button_26
        function Button_26Pushed(app, event)
            % Datas = app.UIAxes.Children;
            TowerID = app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'杆塔')-1);
            %% 读取绝缘子点
            InsPts = app.InsPtsAll{strcmp(app.DropDown_Ins.Items,TowerID)};
            InsPts = InsPts(:,1:3);
            data_tip = app.UIAxes.Children(1).Children;
            if isempty(data_tip)
                msgbox("未选择点")
                return
            end
            selectPtR = [data_tip.X,data_tip.Y,data_tip.Z];
%             drowPts(InsPtsRCut,'.b',selectPtR,'.r')
            labels = dbscan(InsPts,app.Spinner.Value,3);
            Unilabels = unique(labels);
%             drowPts([InsPts,labels],length(Unilabels))
            minDist = inf;
            for i = 1:length(Unilabels)
                CurminDist = min(vecnorm(InsPts(labels==Unilabels(i),:) - selectPtR, 2, 2));
                if CurminDist < minDist
                    minDist = CurminDist;
                    selectLabel = Unilabels(i);
                end
            end
            drowPtsUI(InsPts(labels==selectLabel,:),'.r',InsPts(labels~=selectLabel,:),'.b',app.UIAxes)
        end

        % Button pushed function: Button_27
        function Button_27Pushed(app, event)
            Line = app.UIAxes.Children(2); 
            InsPts = [Line.XData',Line.YData',Line.ZData',];% drowPts(InsPts,'.r')
            InsPtsR3 = RotawithAxle(InsPts,3);% drowPts(InsPtsR3,'.r')
            InsPtsR32 = RotawithAxle(InsPtsR3,2);% drowPts(InsPtsR32,'.r')
            Len = max(InsPtsR32(:,1)) - min(InsPtsR32(:,1));
            % 保存绝缘子长度
            TowerID = str2double(app.UIAxes.Title.String(1:strfind(app.UIAxes.Title.String,'杆塔')-1));
            app.InsLen(app.InsLen(:,1)==TowerID,2) = Len;
            % 重新保存结果
            % savefilename = ['.\Res\',app.TCName,'_InsLen.txt'];
            % writematrix(app.InsLen,savefilename)
            % 显示长度
            app.Status.Value = [app.Status.Value;[app.TCName,' 杆塔',num2str(TowerID),' 绝缘子长度计算 == ',num2str(Len)]];
            read_pts1(app, event)
            % 切换到上一个电塔
            NextID = find(strcmp(app.DropDown_Type.Items,app.DropDown_Type.Value))-1;
            if NextID <= length(app.DropDown_Type.Items) && NextID > 0
                app.DropDown_Type.Value = app.DropDown_Type.Items{NextID};
            end
            % read_pts2(app, event)
            % app.InsLenLabel.Text = ['InsLen = ',num2str(Len)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '处理文件夹';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.Editable = 'off';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @select_folder, true);
            app.Button_6.Text = '选择';
            app.Panel_4 = uipanel(app.GridLayout);
            app.Panel_4.Title = '类型识别';
            app.DropDown_Type = uidropdown(app.Panel_4);
            app.DropDown_Type.Items = {'序号'};
            app.DropDown_Type.Value = '序号';
            app.Button_20 = uibutton(app.Panel_4, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @last_one1, true);
            app.Button_20.Text = '-';
            app.Button_21 = uibutton(app.Panel_4, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @read_pts1, true);
            app.Button_21.Text = '读取';
            app.Button_3 = uibutton(app.Panel_4, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @type_detect, true);
            app.Button_3.Text = '识别所有类型';
            app.Button_17 = uibutton(app.Panel_4, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @single_type, true);
            app.Button_17.Text = '单个类型';
            app.Button_5 = uibutton(app.Panel_4, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @single_Insulator, true);
            app.Button_5.Text = '提绝缘子';
            app.Panel_6 = uipanel(app.GridLayout);
            app.Panel_6.Title = '修改电塔类型';
            app.DropDown_2 = uidropdown(app.Panel_6);
            app.DropDown_2.Items = {'选择类型', '酒杯塔', '猫头塔', ' T 型塔', '干型塔', '耐张鼓型塔', '直流鼓型塔', '干状电塔', '门型塔'};
            app.DropDown_2.Value = '选择类型';
            app.ChangeType = uibutton(app.Panel_6, 'push');
            app.ChangeType.ButtonPushedFcn = createCallbackFcn(app, @change_type, true);
            app.ChangeType.Text = '修改电塔类型并保存';
            app.Panel_5 = uipanel(app.GridLayout);
            app.Panel_5.Title = '批处理';
            app.DropDown = uidropdown(app.Panel_5);
            app.DropDown.Items = {'所有电塔', '酒杯塔', '猫头塔', ' T 型塔', '干型塔', '耐张鼓型塔', '直流鼓型塔', '杆状塔', '门型塔'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '所有电塔';
            app.Button_15 = uibutton(app.Panel_5, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Insulator_extract, true);
            app.Button_15.Text = '批量提取绝缘子';
            app.DropDown_Ins = uidropdown(app.Panel_5);
            app.DropDown_Ins.Items = {'序号'};
            app.DropDown_Ins.Value = '序号';
            app.Button_22 = uibutton(app.Panel_5, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @last_one2, true);
            app.Button_22.Text = '-';
            app.Button_23 = uibutton(app.Panel_5, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @read_pts2, true);
            app.Button_23.Text = '读取';
            app.Status = uitextarea(app.GridLayout);
            app.Status.Editable = 'off';
            app.Button_29 = uibutton(app.GridLayout, 'push');
            app.Button_29.ButtonPushedFcn = createCallbackFcn(app, @ClearStatus, true);
            app.Button_29.Text = '清空';
            app.Panel_7 = uipanel(app.GridLayout);
            app.Panel_7.Title = '参数设置';
            app.Label_4 = uilabel(app.Panel_7);
            app.Label_4.Text = '格网宽度';
            app.Gridwidth = uieditfield(app.Panel_7, 'numeric');
            app.Gridwidth.Value = 0.1;
            app.Panel_8 = uipanel(app.GridLayout);
            app.Panel_8.Title = '绝缘子长度';
            app.kVSpinnerLabel = uilabel(app.Panel_8);
            app.kVSpinnerLabel.Text = '220kV';
            app.Spinner = uispinner(app.Panel_8);
            app.Spinner.Value = 0.5;
            app.Button_26 = uibutton(app.Panel_8, 'push');
            app.Button_26.ButtonPushedFcn = createCallbackFcn(app, @Button_26Pushed, true);
            app.Button_26.Text = '拾取';
            app.Button_27 = uibutton(app.Panel_8, 'push');
            app.Button_27.ButtonPushedFcn = createCallbackFcn(app, @Button_27Pushed, true);
            app.Button_27.Text = '计算长度';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Insulators

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end